classdef PointCloudGUI_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisparityandPointCloudUIFigure  matlab.ui.Figure
        DisparityMapPanel               matlab.ui.container.Panel
        ComputeButton                   matlab.ui.control.Button
        DisparityRangeSpinnerLabel      matlab.ui.control.Label
        DisparityRangeSpinner           matlab.ui.control.Spinner
        DisparityStartSpinnerLabel      matlab.ui.control.Label
        DisparityStartSpinner           matlab.ui.control.Spinner
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        SemiGlobalButton                matlab.ui.control.RadioButton
        BlockMatchingButton             matlab.ui.control.RadioButton
        BlockSizeSliderLabel            matlab.ui.control.Label
        BlockSizeSlider                 matlab.ui.control.Slider
        UniquenessSliderLabel           matlab.ui.control.Label
        UniquenessSlider                matlab.ui.control.Slider
        TextureSliderLabel              matlab.ui.control.Label
        TextureSlider                   matlab.ui.control.Slider
        UIDisparityMap                  matlab.ui.control.UIAxes
        ResetButton                     matlab.ui.control.Button
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        DistanceSliderLabel             matlab.ui.control.Label
        DistanceSlider                  matlab.ui.control.Slider
        SaveButton                      matlab.ui.control.Button
        DisplayPointCloudButton         matlab.ui.control.Button
        ScaleEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        ColorMapButtonGroup             matlab.ui.container.ButtonGroup
        RGBButton                       matlab.ui.control.RadioButton
        IGBButton                       matlab.ui.control.RadioButton
        ZButton                         matlab.ui.control.RadioButton
        THCButton                       matlab.ui.control.RadioButton
        MaskButton                      matlab.ui.control.Button
        RemoveGroundSlider              matlab.ui.control.Slider
        RotRemoveGroundCheckBox         matlab.ui.control.CheckBox
        StreamCheckBox                  matlab.ui.control.CheckBox
        ApplytoAllButton                matlab.ui.control.Button
        ImageRectificationPanel         matlab.ui.container.Panel
        OutputViewButtonGroup           matlab.ui.container.ButtonGroup
        fullButton                      matlab.ui.control.RadioButton
        validButton                     matlab.ui.control.RadioButton
        RectifyButton                   matlab.ui.control.Button
        LoadLastImagesButton            matlab.ui.control.Button
        LoadStereoParametersButton      matlab.ui.control.Button
        WarpButton                      matlab.ui.control.Button
        prev                            matlab.ui.control.Button
        next                            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IGBTab                          matlab.ui.container.Tab
        UIAxesIGB                       matlab.ui.control.UIAxes
        LoadImagesIGB                   matlab.ui.control.Button
        LeafCutButtonIGB                matlab.ui.control.Button
        LoadMaskButtonIGB               matlab.ui.control.Button
        OverlaySwitchLabel              matlab.ui.control.Label
        OverlaySwitch                   matlab.ui.control.RockerSwitch
        THCTab                          matlab.ui.container.Tab
        UIAxesTHC                       matlab.ui.control.UIAxes
        LoadImagesTHC                   matlab.ui.control.Button
        LeafCutButtonTHC                matlab.ui.control.Button
        LoadMaskButtonTHC               matlab.ui.control.Button
        OverlaySwitch_2Label            matlab.ui.control.Label
        OverlaySwitchTHC                matlab.ui.control.RockerSwitch
        NIRTab                          matlab.ui.container.Tab
        VISTab                          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RGBTab                          matlab.ui.container.Tab
        UIAxesRGB                       matlab.ui.control.UIAxes
        LoadImagesRGB                   matlab.ui.control.Button
        LeafCutButtonRGB                matlab.ui.control.Button
        LoadMaskButtonRGB               matlab.ui.control.Button
        CCFilterEditFieldLabel          matlab.ui.control.Label
        CCFilter                        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        stereoParams_RGB_IGB
        stereoParams_RGB_NIR
        stereoParams_RGB_VIS
        stereoParams_RGB_THC
        
        BW_RGB
        BW_IGB
        
        frameRGB
        frameIGB
        frameTHC
        imageSize
        
        frameRGB_rect
        frameIGB_rect
        imageRGB_proj
        imageIGB_proj
        imageTHC_proj
        imageZ_proj
               
        disparityMap
        
        disparityRange
        
        filenamesRGB
        pathnameRGB
        filenamesIGB
        pathnameIGB
        filenamesTHC
        pathnameTHC
        
        i_RGB
        
        imagePointsRGB_unrect
        Rrectl
                 
        ptCloud
        colorsRGB
        colorsIGB
        colorsTHC
   
        R_normal
        ptLoc
        p_model
        ptCloudColor
        indices
        
    end

    methods (Access = private)
        
        function computeDisparity_RGB_IGB(app)
            
            clearOutputs(app);
            
            app.disparityRange = [app.DisparityStartSpinner.Value,...
                app.DisparityStartSpinner.Value + app.DisparityRangeSpinner.Value];
            
            if app.SemiGlobalButton.Value == true
                method = 'SemiGlobal';
            else
                method = 'BlockMatching';
            end
            
            if app.TabGroup.SelectedTab == app.IGBTab
            
                app.disparityMap = disparity(app.frameRGB_rect(:,:,2), app.frameIGB_rect(:,:,2),...
                'Method', method, 'BlockSize', app.BlockSizeSlider.Value,...
                'ContrastThreshold', app.ContrastSlider.Value,...
                'UniquenessThreshold', app.UniquenessSlider.Value,...
                'DistanceThreshold' , app.DistanceSlider.Value,...
                'TextureThreshold' , app.TextureSlider.Value,...
                'DisparityRange', app.disparityRange);
             
                drawimage(app, app.UIDisparityMap, app.disparityMap);
                colorbar(app.UIDisparityMap);
                colormap(app.UIDisparityMap,'jet');
                caxis(app.UIDisparityMap, app.disparityRange);
                
            end

        end
        
        
        function colorsRight = projframeRight2frameLeft(app, stereoParams,frameRight,pts3D)
            
            % THC calibration not perfect :/
%             if frameRight == app.frameTHC
%                 t_cor = [-45.6923705311331	-20	0];
%             else                
%                 t_cor = [0 0 0];
%             end

            imagePointsRight_unrect = worldToImage(stereoParams.CameraParameters2, stereoParams.RotationOfCamera2, stereoParams.TranslationOfCamera2,pts3D, 'ApplyDistortion', true);
            colorsRight = impixel(frameRight, round(imagePointsRight_unrect(:,1)), round(imagePointsRight_unrect(:,2)));     

        end
        
        
        function rectify(app)
            
           if app.fullButton.Value == true          
               outputView = 'full';
           else     
               outputView = 'valid';
           end    
  
            [app.frameRGB_rect, app.frameIGB_rect] = rectifyStereoImages(app.frameRGB, app.frameIGB, app.stereoParams_RGB_IGB, 'OutputView', outputView);

                if app.TabGroup.SelectedTab == app.IGBTab
                
                %stereoRGBIGB = stereoAnaglyph(app.frameRGB_rect,app.frameIGB_rect);
                drawimage(app, app.UIAxesIGB, app.frameIGB_rect);
                drawimage(app, app.UIAxesRGB, app.frameRGB_rect);
              
                end
     
        end
        
        function drawimage(app, UIAxes, image)
            % Fill figure with axes and remove tick labels
            UIAxes.Position = [UIAxes.Position(1:4)];
            % Remove title, axis labels, and tick labels
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imagesc(image, 'Parent', UIAxes, ...
            'XData', [1 UIAxes.Position(3)], ...
            'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];    
            
        end
        
        function [BW,maskedImage] = connectedComponentsFilter(app,maskedImage,distance)
            
%             if class(maskedImage) == 'uint8'
%                 BW = uint8(bwareaopen(rgb2gray(maskedImage),distance));
%             elseif class(maskedImage) == 'uint16'
%                 BW = uint16(bwareaopen(rgb2gray(maskedImage),distance));
%             end
            
            BW = cast(uint8(bwareaopen(rgb2gray(maskedImage),distance)), 'like',maskedImage); 
            
            maskedImage = maskedImage.*BW;
        end
        
        function writeMetaData(app, name)

            metaData = table(app.DisparityStartSpinner.Value,app.DisparityRangeSpinner.Value,...
                    app.BlockSizeSlider.Value,app.UniquenessSlider.Value,...
                    app.TextureSlider.Value,app.ContrastSlider.Value,app.DistanceSlider.Value,...
                'RowNames',{name(1:(length(name)-4))},...
                'VariableNames', {'DisparityStart' 'DisparityRange' 'BlockSize' 'Uniqueness'...
                'Texture' 'Contrast' 'Distance'});
            
            writetable(metaData,name(1:(length(name)-4)))
            
        end
        
        function projCloud2RGB_unrect(app)
            
                [Rl, Rr] = computeHalfRotations_edit(app.stereoParams_RGB_IGB);
                % rotate the transltation vector
                t = Rr * app.stereoParams_RGB_IGB.TranslationOfCamera2';
                % Row align the image planes, by rotating both of them such
                % that the translation vector coinsides with the X-axis.
                RrowAlign = computeRowAlignmentRotation(t);
                % combine rotation matrices
                app.Rrectl = RrowAlign * Rl;
                %Rrectr = RrowAlign * Rr;
                % project 3D points into original (unrectified) RGB frame
                app.imagePointsRGB_unrect = worldToImage(app.stereoParams_RGB_IGB.CameraParameters1,app.Rrectl, [0 0 0] ,app.ptCloud.Location,'ApplyDistortion', true);

        end
        
        function clearOutputs(app)
               app.ptCloud = [];
               app.imagePointsRGB_unrect = [];
               app.p_model = [];
               app.R_normal = [];
               app.colorsRGB = [];
               app.colorsIGB = [];
               app.colorsTHC = [];
               app.imageZ_proj = [];
        end
        
        function projectedImage = point2pixel(app,projectedImage,imagePointsRGB_unrect, colorsRight)
            
      
            for i = 1:size(imagePointsRGB_unrect,1)

                valid = (imagePointsRGB_unrect(i,1) >= 1) & (imagePointsRGB_unrect(i,2) >= 1);

                if(valid)
                    
                    projectedImage(round(imagePointsRGB_unrect(i,2)), round(imagePointsRGB_unrect(i,1)),:) = colorsRight(i,:);
                end
                
            end
           
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxesIGB.Visible = 'off';
            app.UIAxesRGB.Visible = 'off';
            app.UIAxesTHC.Visible = 'off';
            %app.UIAxesIGB_warped.Visible = 'off';
            app.UIDisparityMap.Visible = 'off';
            app.i_RGB = 1;
        end

        % Button pushed function: ApplytoAllButton
        function ApplytoAllButtonPushed(app, event)
            
            app.imageZ_proj = zeros(app.imageSize);
            app.imageZ_proj = point2pixel(app, app.imageZ_proj, app.imagePointsRGB_unrect(app.indices,:), single(app.ptLoc(app.indices,3)));
            
            WarpButtonPushed(app, event);
            
            
            %             app.imageIGB_proj = cast(zeros([app.imageSize,size(app.colorsIGB,2)]), 'like', app.frameIGB);
            %             app.imageIGB_proj = point2pixel(app, app.imageIGB_proj, app.imagePointsRGB_unrect(app.indices,:), app.colorsIGB(app.indices,:));
            %                         
            %             app.imageTHC_proj = cast(zeros([app.imageSize,size(app.colorsTHC,2)]), 'like', app.frameTHC);
            %             app.imageTHC_proj = point2pixel(app, app.imageTHC_proj, app.imagePointsRGB_unrect(app.indices,:), app.colorsTHC(app.indices,:));
                      
            %             app.ColorMapButtonGroup.SelectedObject = app.IGBButton;
            %             app.ColorMapButtonGroup.SelectedObject = app.THCButton;
            %             app.ColorMapButtonGroup.SelectedObject = app.ZButton;       
        end

        % Callback function: BlockSizeSlider, MethodButtonGroup
        function BlockSizeSliderValueChanged(app, event)
            BlockSize = mod(floor(app.BlockSizeSlider.Value),2)*floor(app.BlockSizeSlider.Value) + (1-mod(floor(app.BlockSizeSlider.Value),2))*floor(app.BlockSizeSlider.Value + 1);
            app.BlockSizeSlider.Value = BlockSize;
            computeDisparity_RGB_IGB(app);
        end

        % Selection changed function: ColorMapButtonGroup
        function ColorMapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorMapButtonGroup.SelectedObject;
            
            if selectedButton == app.RGBButton
            
                 app.ptCloudColor = uint8(app.ptCloud.Color);
               
            elseif selectedButton == app.IGBButton
            
                if isempty(app.colorsIGB)
                    app.colorsIGB = projframeRight2frameLeft(app, app.stereoParams_RGB_IGB,app.frameIGB,app.ptCloud.Location * app.Rrectl);
            %                    app.imageIGB_proj = uint8(app.imageIGB_proj);
                    app.colorsIGB = uint8(app.colorsIGB);
                    
                end
                app.ptCloudColor = uint8(app.colorsIGB);
           
           elseif selectedButton == app.THCButton && ~isempty(app.stereoParams_RGB_THC)
                
                if isempty(app.colorsTHC)
                    app.colorsTHC = projframeRight2frameLeft(app, app.stereoParams_RGB_THC,app.frameTHC,... % THC calibration not perfect :/
                                                             app.ptCloud.Location * app.Rrectl);
                                                         
                    %app.imageTHC_proj = app.imageTHC_proj(:,:,1);
                    %app.colorsTHC(isnan(app.colorsTHC)) = 0;
                    app.colorsTHC = app.colorsTHC(:,1);    
                end
                app.ptCloudColor = app.colorsTHC;
            
            elseif selectedButton == app.ZButton
                
                app.ptCloudColor = app.ptLoc(:,3);
                
            %                     if isempty(app.imageZ_proj)
            %                         
            %                         app.imageZ_proj = zeros(app.imageSize);
            %                         for i = 1:size(app.imagePointsRGB_unrect,1)
            %                             valid = (app.imagePointsRGB_unrect(i,1) >= 1) & (app.imagePointsRGB_unrect(i,2) >= 1);
            %                             if(valid)        
            %                             app.imageZ_proj(round(app.imagePointsRGB_unrect(i,2)), round(app.imagePointsRGB_unrect(i,1))) = single(app.ptLoc(i,3));
            %                         
            %                             end    
            %                         end
            %                     end
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            computeDisparity_RGB_IGB(app);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            computeDisparity_RGB_IGB(app);
        end

        % Value changed function: DisparityRangeSpinner
        function DisparityRangeSpinnerValueChanged(app, event)
            computeDisparity_RGB_IGB(app);
        end

        % Value changed function: DisparityStartSpinner
        function DisparityStartSpinnerValueChanged(app, event)
            computeDisparity_RGB_IGB(app);         
        end

        % Button pushed function: DisplayPointCloudButton
        function DisplayPointCloudButtonPushed(app, event)
            if isempty(app.ptCloud)
            
                points3D = reconstructScene(app.disparityMap,app.stereoParams_RGB_IGB);
    
                app.ptCloud = pointCloud(points3D, 'Color',uint8(app.frameRGB_rect));
                app.ptCloud = removeInvalidPoints(app.ptCloud);
                app.ptCloud = pcdenoise(app.ptCloud);
                
                if app.ScaleEditField.Value ~= 1
                    app.ptCloud = pcdownsample(app.ptCloud,'random',app.ScaleEditField.Value);
                end
                
                app.ptLoc = app.ptCloud.Location; 
                projCloud2RGB_unrect(app)
                app.ptCloudColor = app.ptCloud.Color;
               
            end
             
            if isempty(app.p_model)
                 app.indices = 1:1:size(app.ptLoc(:,1));      
            end
            
            if app.StreamCheckBox.Value == 1
                
                player3D = pcplayer([-1200, 1200], [-1500, 1500], [2600, 3800], 'VerticalAxis', 'y', 'VerticalAxisDir', 'down');
       
                while isOpen(player3D)
            
                    view(player3D,app.ptLoc(app.indices,:), app.ptCloudColor(app.indices,:));
                end
            
            else
               
               figure; 
               pcshow(app.ptLoc(app.indices,:), app.ptCloudColor(app.indices,:),'VerticalAxis', 'y', 'VerticalAxisDir', 'down');
               
               
                if app.ColorMapButtonGroup.SelectedObject == app.THCButton
                    caxis(gca, [290, 319]);
                end
               
            end
            % 
            %             if isOpen(player3D) ~= 1
            %                 app.ptCloud = select(app.ptCloud, indices);
            %             end
            %             
        
        end

        % Value changed function: DistanceSlider
        function DistanceSliderValueChanged(app, event)
            Distance = round(app.DistanceSlider.Value);
            app.DistanceSlider.Value = Distance;
            computeDisparity_RGB_IGB(app);
        end

        % Button pushed function: LeafCutButtonIGB
        function LeafCutButtonIGBPushed(app, event)
            colorThresholder(app.frameIGB)
        end

        % Button pushed function: LeafCutButtonRGB
        function LeafCutButtonRGBPushed(app, event)
            colorThresholder(app.frameRGB)
        end

        % Button pushed function: LoadImagesIGB
        function LoadImagesIGBButtonPushed(app, event)
            [app.filenamesIGB,app.pathnameIGB] = uigetfile({'*.jpg;*.tif;*.TIF;*.png;*.gif','All Image Files'}, 'MultiSelect', 'on');
            app.frameIGB = imread([app.pathnameIGB, app.filenamesIGB{app.i_RGB}]);
            %app.frameIGB = imread([app.pathnameIGB, app.filenamesIGB]);
            drawimage(app, app.UIAxesIGB, app.frameIGB);
            imwrite(app.frameIGB,'frameRight.tif');
        end

        % Button pushed function: LoadImagesRGB
        function LoadImagesRGBPushed(app, event)
            [app.filenamesRGB,app.pathnameRGB] = uigetfile({'*.jpg;*.tif;*.TIF;*.png;*.gif','All Image Files'}, 'MultiSelect', 'on');
            app.frameRGB = imread([app.pathnameRGB, app.filenamesRGB{app.i_RGB}]);
            %app.frameRGB = imread([app.pathnameRGB, app.filenamesRGB]);
            app.imageSize = size(app.frameRGB(:,:,1));
            drawimage(app, app.UIAxesRGB, app.frameRGB);
            imwrite(app.frameRGB,'frameLeft.tif');
        end

        % Button pushed function: LoadImagesTHC
        function LoadImagesTHCButtonPushed(app, event)
            [app.filenamesTHC,app.pathnameTHC] = uigetfile({'*.jpg;*.tif;*.TIF;*.png;*.gif','All Image Files'}, 'MultiSelect', 'on');
            app.frameTHC = imread([app.pathnameTHC, app.filenamesTHC{app.i_RGB}]);
            %app.frameTHC = imread([app.pathnameTHC, app.filenamesTHC]);
            drawimage(app, app.UIAxesTHC, app.frameTHC);
            writeMultiChannelTIFF(app.frameTHC, 'frameRightTHC.tif', 32)
        end

        % Button pushed function: LoadLastImagesButton
        function LoadLastImagesButtonPushed(app, event)
            load('stereoParams_RGB_IGB.mat');
            app.stereoParams_RGB_IGB = stereoParams_RGB_IGB;
            load('stereoParams_RGB_THC.mat');
            app.stereoParams_RGB_THC = stereoParams_RGB_THC;
            app.frameIGB = imread('frameRight.tif');
            drawimage(app, app.UIAxesIGB, app.frameIGB);
            app.frameRGB = imread('frameLeft.tif');         
            app.imageSize = size(app.frameRGB(:,:,1));
            drawimage(app, app.UIAxesRGB, app.frameRGB);
            app.frameTHC = imread('frameRightTHC.tif');
            drawimage(app, app.UIAxesTHC, app.frameTHC);
        end

        % Button pushed function: LoadMaskButtonIGB
        function LoadMaskButtonIGBPushed(app, event)
            %             olddir = pwd;
            %             MaskIGB_Dir = uigetdir();
            %             cd(MaskIGB_Dir);
            [app.BW_IGB,app.frameIGB] = MaskIGB(app.frameIGB);
            
            [BW,app.frameIGB] = connectedComponentsFilter(app,app.frameIGB,app.CCFilter.Value);
            
            app.BW_IGB = cast(app.BW_IGB, 'like', BW);
            app.BW_IGB = app.BW_IGB.*BW;
            
            drawimage(app, app.UIAxesIGB, app.frameIGB);
            
        end

        % Button pushed function: LoadMaskButtonRGB
        function LoadMaskButtonRGBPushed(app, event)
            %             olddir = pwd;
            %             MaskRGB_Dir = uigetdir();
            %             cd(MaskRGB_Dir);
            [app.BW_RGB,app.frameRGB] = MaskRGB(app.frameRGB);
            %             cd(olddir);
            [BW,app.frameRGB] = connectedComponentsFilter(app,app.frameRGB,app.CCFilter.Value);
            
            app.BW_RGB = cast(app.BW_RGB, 'like', BW);
            app.BW_RGB = app.BW_RGB.*BW;
            
            drawimage(app, app.UIAxesRGB, app.frameRGB);
                        
        end

        % Button pushed function: LoadStereoParametersButton
        function LoadStereoParametersButtonPushed(app, event)
            olddir = pwd;
            stereoParamsDir = uigetdir();
            cd(stereoParamsDir);
            if exist('stereoParams_RGB_IGB.mat', 'file')
                load('stereoParams_RGB_IGB.mat');
                app.stereoParams_RGB_IGB = stereoParams_RGB_IGB;
            %                 save('stereoParams_RGB_IGB.mat', 'stereoParams_RGB_IGB');
            %                 showExtrinsics_mod(app.stereoParams_RGB_IGB,'IGB','r');
            end
            if exist('stereoParams_RGB_NIR.mat', 'file')
                load('stereoParams_RGB_NIR.mat');
                app.stereoParams_RGB_NIR = stereoParams_RGB_NIR;
            end
            if exist('stereoParams_RGB_VIS.mat', 'file')
                load('stereoParams_RGB_VIS.mat');
                app.stereoParams_RGB_VIS = stereoParams_RGB_VIS;
            end
            if exist('stereoParams_RGB_THC.mat', 'file')
                load('stereoParams_RGB_THC.mat');
                app.stereoParams_RGB_THC = stereoParams_RGB_THC;
            end
            cd(olddir);
            %             save('stereoParams_RGB_IGB.mat', 'stereoParams_RGB_IGB');
            %             save('stereoParams_RGB_NIR.mat', 'stereoParams_RGB_NIR');
            %             save('stereoParams_RGB_VIS.mat', 'stereoParams_RGB_VIS');
            %             save('stereoParams_RGB_THC.mat', 'stereoParams_RGB_THC');
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            
            app.disparityMap = app.disparityMap.*bwareaopen(app.frameRGB_rect(:,:,2),app.CCFilter.Value);
            
            drawimage(app, app.UIDisparityMap, app.disparityMap);
        end

        % Size changed function: MethodButtonGroup
        function MethodButtonGroupSizeChanged(app, event)
            computeDisparity_RGB_IGB(app);
        end

        % Button pushed function: next
        function NextButtonPushed(app, event)
            if app.i_RGB <= length(app.filenamesRGB) - 1
               app.i_RGB = app.i_RGB + 1;
               app.frameRGB = imread([app.pathnameRGB, app.filenamesRGB{app.i_RGB}]);
               drawimage(app, app.UIAxesRGB, app.frameRGB);
               app.frameIGB = imread([app.pathnameIGB, app.filenamesIGB{app.i_RGB}]);
               drawimage(app, app.UIAxesIGB, app.frameIGB);
               app.frameTHC = imread([app.pathnameTHC, app.filenamesTHC{app.i_RGB}]);
               drawimage(app, app.UIAxesTHC, app.frameTHC);
            %                LoadMaskButtonRGBPushed(app, event);
            %                LoadMaskButtonIGBPushed(app, event);
               clearOutputs(app);
            end
        end

        % Selection changed function: OutputViewButtonGroup
        function OutputViewButtonGroupSelectionChanged(app, event)
            rectify(app);
        end

        % Value changed function: OverlaySwitch
        function OverlaySwitchValueChanged(app, event)
            if app.OverlaySwitch.Value == 'On'
                drawimage(app, app.UIAxesIGB_warped, imfuse(app.frameRGB_rect,app.imageIGB_proj));
            elseif app.OverlaySwitch.Value == 'Off'
                drawimage(app, app.UIAxesIGB_warped, app.imageIGB_proj);
            end
        end

        % Button pushed function: prev
        function PrevButtonPushed(app, event)
            if app.i_RGB > 2
               app.i_RGB = app.i_RGB - 1;
               app.frameRGB = imread([app.pathnameRGB, app.filenamesRGB{app.i_RGB}]);
               drawimage(app, app.UIAxesRGB, app.frameRGB);
               app.frameIGB = imread([app.pathnameIGB, app.filenamesIGB{app.i_RGB}]);
               drawimage(app, app.UIAxesIGB, app.frameIGB);
               app.frameTHC = imread([app.pathnameTHC, app.filenamesTHC{app.i_RGB}]);
               drawimage(app, app.UIAxesTHC, app.frameTHC);
             
            %                LoadMaskButtonRGBPushed(app, event);
            %                LoadMaskButtonIGBPushed(app, event);
               clearOutputs(app);
            end
        end

        % Button pushed function: RectifyButton
        function RectifyButtonPushed(app, event)
            rectify(app);
        end

        % Value changed function: RemoveGroundSlider
        function RemoveGroundSliderValueChanged(app, event)
              app.indices = find(app.ptLoc(:,3) < -( app.p_model.Parameters(1,4) + app.RemoveGroundSlider.Value));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UniquenessSlider.Value = 15;
            app.TextureSlider.Value = 0.0002;
            app.BlockSizeSlider.Value = 15;
            app.ContrastSlider.Value = 0.5;
            app.DistanceSlider.Value = 0;
            ComputeButtonPushed(app, event);
            %            ComputeButtonPushed(app, event);
            clearOutputs(app);
        end

        % Value changed function: RotRemoveGroundCheckBox
        function RotRemoveGroundCheckBoxValueChanged(app, event)
            value = app.RotRemoveGroundCheckBox.Value;
                    
                app.imageZ_proj = [];
                
                if isempty(app.R_normal)
                        
                    % Set the maximum point-to-plane distance (5cm) for plane fitting.
                    maxDistance = 25;
                    %%
                    % Set the normal vector of the plane.
                    referenceVector = [0,0,1];
                    %%
                    % Set the maximum angular distance to 5 degrees.
                    maxAngularDistance = 5;
                    %%
                    % Detect the first plane, the table, and extract it from the point cloud.
                    [app.p_model,~,~] = pcfitplane(app.ptCloud,...
                                maxDistance,referenceVector,maxAngularDistance);
                    %plane = select(app.ptCloud,inlierIndices);
                    
                    % Rotate noral plane onto to Z-axis
                    app.R_normal = vrrotvec2mat(vrrotvec(app.p_model.Normal,[0 0 1]));
                end
                
                if value == 1
                    app.ptLoc = app.ptCloud.Location * app.R_normal';
                else    
                    app.ptLoc = app.ptCloud.Location;
                end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disparityMap = app.disparityMap;
            if isempty(app.filenamesRGB)
                name = 'Dummy1234';
            else
                name = app.filenamesRGB{app.i_RGB};
            end
            %name = app.filenamesRGB;
            save([name(1:(length(name)-4)) '_dispMapIGB.mat'], 'disparityMap');
            writeMetaData(app, name);
            
            dataCube = cat(3, single(app.imageRGB_proj), single(app.imageIGB_proj), app.imageTHC_proj, app.imageZ_proj);
            writeMultiChannelTIFF(dataCube,[name(1:(length(name)-4)) '_Cube.tif'], 32);
            
        end

        % Value changed function: TextureSlider
        function TextureSliderValueChanged(app, event)
            if app.TextureSlider.Value == 1.0
            app.TextureSlider.Value = 0.9999;
            end
            computeDisparity_RGB_IGB(app);
        end

        % Value changed function: UniquenessSlider
        function UniquenessSliderValueChanged(app, event)
            Uniqueness = round(app.UniquenessSlider.Value);
            app.UniquenessSlider.Value = Uniqueness;
            computeDisparity_RGB_IGB(app);
        end

        % Button pushed function: WarpButton
        function WarpButtonPushed(app, event)
            app.colorsRGB = impixel(app.frameRGB, round(app.imagePointsRGB_unrect(:,1)), round(app.imagePointsRGB_unrect(:,2)));     
            app.imageRGB_proj = cast(zeros([app.imageSize,size(app.colorsRGB,2)]), 'like', app.frameRGB);
            app.imageRGB_proj = point2pixel(app, app.imageRGB_proj, app.imagePointsRGB_unrect(app.indices,:), app.colorsRGB(app.indices,:));
            drawimage(app, app.UIAxesRGB, app.imageRGB_proj);
             
            app.imageIGB_proj = cast(zeros([app.imageSize,size(app.colorsIGB,2)]), 'like', app.frameIGB);
            app.imageIGB_proj = point2pixel(app, app.imageIGB_proj, app.imagePointsRGB_unrect(app.indices,:), app.colorsIGB(app.indices,:));
            drawimage(app, app.UIAxesIGB, app.imageIGB_proj);
            
            app.imageTHC_proj = cast(zeros([app.imageSize,size(app.colorsTHC,2)]), 'like', app.frameTHC);
            app.imageTHC_proj = point2pixel(app, app.imageTHC_proj, app.imagePointsRGB_unrect(app.indices,:), app.colorsTHC(app.indices,:));
            drawimage(app, app.UIAxesTHC, app.imageTHC_proj);
            caxis(app.UIAxesTHC, [290, 320]);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisparityandPointCloudUIFigure = uifigure;
            app.DisparityandPointCloudUIFigure.Name = 'Disparity and PointCloud';
            app.DisparityMapPanel = uipanel(app.DisparityandPointCloudUIFigure);
            app.DisparityMapPanel.Title = 'Disparity Map';
            app.ComputeButton = uibutton(app.DisparityMapPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.DisparityRangeSpinnerLabel = uilabel(app.DisparityMapPanel);
            app.DisparityRangeSpinnerLabel.Text = 'Disparity Range';
            app.DisparityRangeSpinner = uispinner(app.DisparityMapPanel);
            app.DisparityRangeSpinner.Step = 16;
            app.DisparityRangeSpinner.ValueChangedFcn = createCallbackFcn(app, @DisparityRangeSpinnerValueChanged, true);
            app.DisparityRangeSpinner.Limits = [16 Inf];
            app.DisparityRangeSpinner.Value = 32;
            app.DisparityStartSpinnerLabel = uilabel(app.DisparityMapPanel);
            app.DisparityStartSpinnerLabel.Text = 'Disparity Start';
            app.DisparityStartSpinner = uispinner(app.DisparityMapPanel);
            app.DisparityStartSpinner.Step = 16;
            app.DisparityStartSpinner.ValueChangedFcn = createCallbackFcn(app, @DisparityStartSpinnerValueChanged, true);
            app.DisparityStartSpinner.Value = 120;
            app.MethodButtonGroup = uibuttongroup(app.DisparityMapPanel);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BlockSizeSliderValueChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.MethodButtonGroup.SizeChangedFcn = createCallbackFcn(app, @MethodButtonGroupSizeChanged, true);
            app.SemiGlobalButton = uiradiobutton(app.MethodButtonGroup);
            app.SemiGlobalButton.Text = 'SemiGlobal';
            app.SemiGlobalButton.Value = true;
            app.BlockMatchingButton = uiradiobutton(app.MethodButtonGroup);
            app.BlockMatchingButton.Text = 'BlockMatching';
            app.BlockSizeSliderLabel = uilabel(app.DisparityMapPanel);
            app.BlockSizeSliderLabel.Text = 'BlockSize';
            app.BlockSizeSlider = uislider(app.DisparityMapPanel);
            app.BlockSizeSlider.Limits = [5 35];
            app.BlockSizeSlider.ValueChangedFcn = createCallbackFcn(app, @BlockSizeSliderValueChanged, true);
            app.BlockSizeSlider.Value = 5;
            app.UniquenessSliderLabel = uilabel(app.DisparityMapPanel);
            app.UniquenessSliderLabel.Text = 'Uniqueness';
            app.UniquenessSlider = uislider(app.DisparityMapPanel);
            app.UniquenessSlider.Limits = [0 30];
            app.UniquenessSlider.ValueChangedFcn = createCallbackFcn(app, @UniquenessSliderValueChanged, true);
            app.TextureSliderLabel = uilabel(app.DisparityMapPanel);
            app.TextureSliderLabel.Text = 'Texture';
            app.TextureSlider = uislider(app.DisparityMapPanel);
            app.TextureSlider.Limits = [0 0.12];
            app.TextureSlider.ValueChangedFcn = createCallbackFcn(app, @TextureSliderValueChanged, true);
            app.TextureSlider.Value = 0.0002;
            app.UIDisparityMap = uiaxes(app.DisparityMapPanel);
            title(app.UIDisparityMap, 'Disparity Map')
            app.ResetButton = uibutton(app.DisparityMapPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ContrastSliderLabel = uilabel(app.DisparityMapPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.DisparityMapPanel);
            app.ContrastSlider.Limits = [0 1];
            app.ContrastSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 0.5;
            app.DistanceSliderLabel = uilabel(app.DisparityMapPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.DisparityMapPanel);
            app.DistanceSlider.ValueChangedFcn = createCallbackFcn(app, @DistanceSliderValueChanged, true);
            app.SaveButton = uibutton(app.DisparityMapPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DisplayPointCloudButton = uibutton(app.DisparityMapPanel, 'push');
            app.DisplayPointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPointCloudButtonPushed, true);
            app.DisplayPointCloudButton.Text = 'Display PointCloud';
            app.ScaleEditFieldLabel = uilabel(app.DisparityMapPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.DisparityMapPanel, 'numeric');
            app.ScaleEditField.Value = 1;
            app.ColorMapButtonGroup = uibuttongroup(app.DisparityMapPanel);
            app.ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroupSelectionChanged, true);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.RGBButton = uiradiobutton(app.ColorMapButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.IGBButton = uiradiobutton(app.ColorMapButtonGroup);
            app.IGBButton.Text = 'IGB';
            app.ZButton = uiradiobutton(app.ColorMapButtonGroup);
            app.ZButton.Text = 'Z';
            app.THCButton = uiradiobutton(app.ColorMapButtonGroup);
            app.THCButton.Text = 'THC';
            app.MaskButton = uibutton(app.DisparityMapPanel, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.RemoveGroundSlider = uislider(app.DisparityMapPanel);
            app.RemoveGroundSlider.Limits = [-50 100];
            app.RemoveGroundSlider.ValueChangedFcn = createCallbackFcn(app, @RemoveGroundSliderValueChanged, true);
            app.RotRemoveGroundCheckBox = uicheckbox(app.DisparityMapPanel);
            app.RotRemoveGroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotRemoveGroundCheckBoxValueChanged, true);
            app.RotRemoveGroundCheckBox.Text = {'Rot & Remove'; ' Ground'};
            app.StreamCheckBox = uicheckbox(app.DisparityMapPanel);
            app.StreamCheckBox.Text = 'Stream';
            app.ApplytoAllButton = uibutton(app.DisparityMapPanel, 'push');
            app.ApplytoAllButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoAllButtonPushed, true);
            app.ApplytoAllButton.Text = 'Apply to All ';
            app.ImageRectificationPanel = uipanel(app.DisparityandPointCloudUIFigure);
            app.ImageRectificationPanel.Title = 'Image Rectification';
            app.OutputViewButtonGroup = uibuttongroup(app.ImageRectificationPanel);
            app.OutputViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputViewButtonGroupSelectionChanged, true);
            app.OutputViewButtonGroup.Title = 'Output View';
            app.fullButton = uiradiobutton(app.OutputViewButtonGroup);
            app.fullButton.Text = 'full';
            app.validButton = uiradiobutton(app.OutputViewButtonGroup);
            app.validButton.Text = 'valid';
            app.validButton.Value = true;
            app.RectifyButton = uibutton(app.ImageRectificationPanel, 'push');
            app.RectifyButton.ButtonPushedFcn = createCallbackFcn(app, @RectifyButtonPushed, true);
            app.RectifyButton.Text = 'Rectify';
            app.LoadLastImagesButton = uibutton(app.ImageRectificationPanel, 'push');
            app.LoadLastImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastImagesButtonPushed, true);
            app.LoadLastImagesButton.Text = {'Load'; 'Last Images'};
            app.LoadStereoParametersButton = uibutton(app.ImageRectificationPanel, 'push');
            app.LoadStereoParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStereoParametersButtonPushed, true);
            app.LoadStereoParametersButton.Text = {'Load'; 'StereoParameters'};
            app.WarpButton = uibutton(app.ImageRectificationPanel, 'push');
            app.WarpButton.ButtonPushedFcn = createCallbackFcn(app, @WarpButtonPushed, true);
            app.WarpButton.Text = 'Warp';
            app.prev = uibutton(app.ImageRectificationPanel, 'push');
            app.prev.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.prev.Text = '<-';
            app.next = uibutton(app.ImageRectificationPanel, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.next.Text = '->';
            app.TabGroup = uitabgroup(app.DisparityandPointCloudUIFigure);
            app.IGBTab = uitab(app.TabGroup);
            app.IGBTab.Title = 'IGB';
            app.UIAxesIGB = uiaxes(app.IGBTab);
            app.LoadImagesIGB = uibutton(app.IGBTab, 'push');
            app.LoadImagesIGB.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesIGBButtonPushed, true);
            app.LoadImagesIGB.Text = {'Load'; 'Images'};
            app.LeafCutButtonIGB = uibutton(app.IGBTab, 'push');
            app.LeafCutButtonIGB.ButtonPushedFcn = createCallbackFcn(app, @LeafCutButtonIGBPushed, true);
            app.LeafCutButtonIGB.Text = {'Leaf'; 'Cut'};
            app.LoadMaskButtonIGB = uibutton(app.IGBTab, 'push');
            app.LoadMaskButtonIGB.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonIGBPushed, true);
            app.LoadMaskButtonIGB.Text = {'Load'; 'Mask'};
            app.OverlaySwitchLabel = uilabel(app.IGBTab);
            app.OverlaySwitchLabel.Text = 'Overlay';
            app.OverlaySwitch = uiswitch(app.IGBTab, 'rocker');
            app.OverlaySwitch.ValueChangedFcn = createCallbackFcn(app, @OverlaySwitchValueChanged, true);
            app.THCTab = uitab(app.TabGroup);
            app.THCTab.Title = 'THC';
            app.UIAxesTHC = uiaxes(app.THCTab);
            app.UIAxesTHC.XLim = [0 1];
            app.UIAxesTHC.XLimMode = 'manual';
            app.UIAxesTHC.YLim = [0 1];
            app.UIAxesTHC.YLimMode = 'manual';
            app.UIAxesTHC.XTick = [0 0.5 1];
            app.UIAxesTHC.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.LoadImagesTHC = uibutton(app.THCTab, 'push');
            app.LoadImagesTHC.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesTHCButtonPushed, true);
            app.LoadImagesTHC.Text = {'Load'; 'Images'};
            app.LeafCutButtonTHC = uibutton(app.THCTab, 'push');
            app.LeafCutButtonTHC.Text = {'Leaf'; 'Cut'};
            app.LoadMaskButtonTHC = uibutton(app.THCTab, 'push');
            app.LoadMaskButtonTHC.Text = {'Load'; 'Mask'};
            app.OverlaySwitch_2Label = uilabel(app.THCTab);
            app.OverlaySwitch_2Label.Text = 'Overlay';
            app.OverlaySwitchTHC = uiswitch(app.THCTab, 'rocker');
            app.NIRTab = uitab(app.TabGroup);
            app.NIRTab.Title = 'NIR';
            app.VISTab = uitab(app.TabGroup);
            app.VISTab.Title = 'VIS';
            app.TabGroup2 = uitabgroup(app.DisparityandPointCloudUIFigure);
            app.RGBTab = uitab(app.TabGroup2);
            app.RGBTab.Title = 'RGB';
            app.UIAxesRGB = uiaxes(app.RGBTab);
            app.UIAxesRGB.XLim = [0 1];
            app.UIAxesRGB.XLimMode = 'manual';
            app.UIAxesRGB.YLim = [0 1];
            app.UIAxesRGB.YLimMode = 'manual';
            app.UIAxesRGB.CLim = [0 1];
            app.UIAxesRGB.CLimMode = 'manual';
            app.UIAxesRGB.XTick = [0 0.5 1];
            app.UIAxesRGB.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.LoadImagesRGB = uibutton(app.RGBTab, 'push');
            app.LoadImagesRGB.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesRGBPushed, true);
            app.LoadImagesRGB.Text = {'Load'; 'Images'};
            app.LeafCutButtonRGB = uibutton(app.RGBTab, 'push');
            app.LeafCutButtonRGB.ButtonPushedFcn = createCallbackFcn(app, @LeafCutButtonRGBPushed, true);
            app.LeafCutButtonRGB.Text = {'Leaf'; 'Cut'};
            app.LoadMaskButtonRGB = uibutton(app.RGBTab, 'push');
            app.LoadMaskButtonRGB.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonRGBPushed, true);
            app.LoadMaskButtonRGB.Text = {'Load'; 'Mask'};
            app.CCFilterEditFieldLabel = uilabel(app.RGBTab);
            app.CCFilterEditFieldLabel.Text = 'CC Filter';
            app.CCFilter = uieditfield(app.RGBTab, 'numeric');
            app.CCFilter.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = PointCloudGUI_demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisparityandPointCloudUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisparityandPointCloudUIFigure)
        end
    end
end